classdef Aztec_Diamond_V2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        NumberofrunsEditField  matlab.ui.control.NumericEditField
        Button                 matlab.ui.control.Button
        AztecNumberALabel      matlab.ui.control.Label
        Noteasofversion2errorsoccurwhenmakingA1orA2Label  matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    methods (Access = private)

        
        function [A_N,E_W_Canceled,N_S_Canceled] = Canceling_The_Numbers(~,A_N,n,m,E_W_Canceled,N_S_Canceled)
            % this I hope will check for parts that cancel each other out
            for k_row = 1 : n - 1 % steps through the row of the matrix, n is the row
                for k_column = 1 : m - 1 % steps through the column of the matrix, m is the column
                    if A_N(k_row,k_column) == 7 && A_N(k_row + 1,k_column) == 7 && A_N(k_row,k_column + 1) == 9 && A_N(k_row + 1,k_column + 1) == 9
                        A_N(k_row,k_column)         = 0; % This is effectively canceling out each term
                        A_N(k_row + 1,k_column)     = 0;
                        A_N(k_row,k_column + 1)     = 0;
                        A_N(k_row + 1,k_column + 1) = 0;
                        E_W_Canceled = true;
                    elseif A_N(k_row,k_column) == 8 && A_N(k_row,k_column + 1) == 8 && A_N(k_row + 1,k_column) == 6 && A_N(k_row + 1,k_column + 1) == 6
                        A_N(k_row,k_column)         = 0; % This is effectively canceling out each term
                        A_N(k_row + 1,k_column)     = 0;
                        A_N(k_row,k_column + 1)     = 0;
                        A_N(k_row + 1,k_column + 1) = 0;
                        N_S_Canceled = true;
                    end
                end
            end
        end
        
        
        function A_N = Moving_And_Making_5(~,A_N,n,m)
            % This is for the upper left corner
            for k_row = 1 : n/2 % steps through the row of the matrix, n is the row
                for k_column = 1 : m/2 % steps through the column of the matrix, m is the column
                    if A_N(k_row,k_column) == 5 % first check on the 5's
                        A_N(k_row - 1,k_column - 1) = 5;
                        A_N(k_row,k_column)         = 0;
                        A_N(k_row - 1,k_column)     = 5;
                        A_N(k_row,k_column - 1)     = 5;
                    end
                end
            end
            % This is for the upper right corner
            for k_row = 1 : n/2 % steps through the row of the matrix, n is the row
                for k_column = m : -1 : m/2 + 1 % steps through the column of the matrix, m is the column
                    if A_N(k_row,k_column) == 5 % first check on the 5's
                        A_N(k_row - 1,k_column + 1) = 5;
                        A_N(k_row,k_column)         = 0;
                        A_N(k_row - 1,k_column)     = 5;
                        A_N(k_row,k_column + 1)     = 5;
                    end
                end
            end
            % This is for the lower right corner
            for k_row = n : -1 : n/2 + 1 % steps through the row of the matrix, n is the row
                for k_column = m : -1 : m/2 + 1 % steps through the column of the matrix, m is the column
                    if A_N(k_row,k_column) == 5 % first check on the 5's
                        A_N(k_row + 1,k_column + 1) = 5;
                        A_N(k_row,k_column)         = 0;
                        A_N(k_row + 1,k_column)     = 5;
                        A_N(k_row,k_column + 1)     = 5;
                    end
                end
            end
            % This is for the lower left corner
            for k_row = n : -1 : n/2 + 1 % steps through the row of the matrix, n is the row
                for k_column = 1: m/2 % steps through the column of the matrix, m is the column
                    if A_N(k_row,k_column) == 5 % first check on the 5's
                        A_N(k_row + 1,k_column - 1) = 5;
                        A_N(k_row,k_column)         = 0;
                        A_N(k_row + 1,k_column)     = 5;
                        A_N(k_row,k_column - 1)     = 5;
                    end
                end
            end
        end

        
        function [A_N,E_W_Canceled,N_S_Canceled] = Filling_In(~,A_N,n,m)
            for k_row = 1 : n % steps through the row of the matrix, n is the row
                for k_column = 1 : m % steps through the column of the matrix, m is the column
                    if A_N(k_row,k_column) < 1 % an element is zero, yay
                        if A_N(k_row + 1,k_column) < 1 % Find the Zeros square
                            if A_N(k_row,k_column + 1) < 1
                                if A_N(k_row + 1,k_column + 1) < 1
                                    % code here for placing the 6, 7, 8, 9
                                    % 1st generate which direction, either
                                    % North or West
                                    Seed = randi([0,1],1);
                                    if Seed < 1 % here if Seed = 0
                                        Direction = 6; % this means North and South
                                    elseif Seed > 0 % here if Seed = 1
                                        Direction = 9; % this means East and West
                                    end
                                    if Direction == 6 % NORTH AND SOUTH
                                        A_N(k_row,k_column)         = 6; % North
                                        A_N(k_row,k_column + 1)     = 6; % North
                                        A_N(k_row + 1,k_column)     = 8; % South
                                        A_N(k_row + 1,k_column + 1) = 8; % South
                                    elseif Direction == 9 % EAST AND WEST
                                        A_N(k_row,k_column)         = 9; % West
                                        A_N(k_row,k_column + 1)     = 7; % East
                                        A_N(k_row + 1,k_column)     = 9; % West
                                        A_N(k_row + 1,k_column + 1) = 7; % East
                                    end
                                end
                            end
                        end
                        E_W_Canceled = false;
                        N_S_Canceled = false;
                    end
                end
            end
        end

        
        function A_N = Moving_The_Numbers(~,A_N,n,m)
            % Moving the numbers
            % copying the numbers to the right position
            A_N_5s = zeros([n,m]);
            [n_5_1,m_5_1] = find(A_N==5);
            [n_5_2,~] = size(n_5_1);
            for hoopla_counter = 1:n_5_2
                A_N_5s(n_5_1(hoopla_counter),m_5_1(hoopla_counter)) = 5;
            end
            A_N_9s = zeros([n,m]);
            [n_9_1,m_9_1] = find(A_N==9);
            [n_9_2,~] = size(n_9_1);
            for hoopla_counter = 1:n_9_2
                A_N_9s(n_9_1(hoopla_counter),m_9_1(hoopla_counter)) = 9;
            end
            A_N_8s = zeros([n,m]);
            [n_8_1,m_8_1] = find(A_N==8);
            [n_8_2,~] = size(n_8_1);
            for hoopla_counter = 1:n_8_2
                A_N_8s(n_8_1(hoopla_counter),m_8_1(hoopla_counter)) = 8;
            end
            A_N_7s = zeros([n,m]);
            [n_7_1,m_7_1] = find(A_N==7);
            [n_7_2,~] = size(n_7_1);
            for hoopla_counter = 1:n_7_2
                A_N_7s(n_7_1(hoopla_counter),m_7_1(hoopla_counter)) = 7;
            end
            A_N_6s = zeros([n,m]);
            [n_6_1,m_6_1] = find(A_N==6);
            [n_6_2,~] = size(n_6_1);
            for hoopla_counter = 1:n_6_2
                A_N_6s(n_6_1(hoopla_counter),m_6_1(hoopla_counter)) = 6;
            end
            
            % moving the numbers
            for k_column = 1:m % for 9's
                for k_row = 1:n
                    if A_N_9s(k_row,k_column) == 9
                        A_N_9s(k_row,k_column - 1)     = 9;
                        A_N_9s(k_row + 1,k_column - 1) = 9;
                        A_N_9s(k_row,k_column)         = 0;
                        A_N_9s(k_row + 1,k_column)     = 0;
                    end
                end
            end
            for k_row = n:-1:1 % for 8's
                for k_column = 1:m
                    if A_N_8s(k_row,k_column) == 8
                        A_N_8s(k_row + 1,k_column)     = 8;
                        A_N_8s(k_row + 1,k_column + 1) = 8;
                        A_N_8s(k_row,k_column)         = 0;
                        A_N_8s(k_row,k_column + 1)     = 0;
                    end
                end
            end
            for k_column = m:-1:1 % for 7's
                for k_row = 1:n
                    if A_N_7s(k_row,k_column) == 7
                        A_N_7s(k_row,k_column + 1)     = 7;
                        A_N_7s(k_row + 1,k_column + 1) = 7;
                        A_N_7s(k_row,k_column)         = 0;
                        A_N_7s(k_row + 1,k_column)     = 0;
                    end
                end
            end
            for k_row = 1:n % for 6's
                for k_column = 1:m
                    if A_N_6s(k_row,k_column) == 6
                        A_N_6s(k_row - 1,k_column)     = 6;
                        A_N_6s(k_row - 1,k_column + 1) = 6;
                        A_N_6s(k_row,k_column)         = 0;
                        A_N_6s(k_row,k_column + 1)     = 0;
                    end
                end
            end
            A_N = A_N_6s + A_N_7s + A_N_8s + A_N_9s + A_N_5s;
        end % end of Moving_The_Numbers

        function Zeros = Making_Zeroes(~,x,y)
        % This function appends zeroes to the outer boarder of a square matrix
        % Top and bottom of nxm
        Zeros = cat(1,zeros(1,x,'double'),y);
        Zeros = cat(1,Zeros,zeros(1,x,'double'));
        % Sides of nxm
        x = x + 2;
        Zeros = cat(2,zeros(x,1,'double'),Zeros);
        Zeros = cat(2,Zeros,zeros(x,1,'double'));
        end % end of Making_Zeroes
        
        function f = More_Detail_Waitbar(~,counter, N_count,f)
            if counter == 0
                f = waitbar(0,'1','Name','Loading...',...
                    'CreateCancelBtn','setappdata(gcbf,''canceling'',1)');
                setappdata(f,'canceling',0)
            else
                waitbar(counter/N_count,f,sprintf('%.0f%% complete',(counter/N_count)*100))
            end    
        end % end of More_Detail_Waitbar
        
    end % end of function calling the functions
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxes,'off') %turns off the axis initially
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%% Main function Code %%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % this reads the number entered into the user field
            N_count = app.NumberofrunsEditField.Value;
            counter = 0; % other counter for if statements
            f = 0; % initializes f to be zero
            j = true; % random variable to continue the while loop
            % 6 is North, 7 is East, 8 is South, 9 is West
            while j == true
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%%%%%%%%%%%%%%%%% loading screen %%%%%%%%%%%%%%%%%%%%%%%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                f = More_Detail_Waitbar(app,counter, N_count,f);
                if getappdata(f,'canceling')
                    if hoopla == true
                    end
                    if exist("f", "var") == 1
                        delete(f);
                    end
                    break
                end
                if counter == N_count
                    j = false;
                    delete(f);
                end
                %%%%%%%%%%%%%%%%%%End of loading screen %%%%%%%%%%%%%%%%%%%
                
                if counter == 0 % this if for the first run
                    A_N = [0,0;0,0];     %2x2
                    % find the first zero
                    [n,m] = size(A_N);
                    [A_N,E_W_Canceled,N_S_Canceled] = Filling_In(app,A_N,n,m);
                elseif counter == 1 % this is for the 2nd run
                    A_N = Making_Zeroes(app,2,A_N); % for the first time it is 2
                    [n,m] = size(A_N);
                    A_N(4,4) = 5; A_N(1,1) = 5; A_N(1,4) = 5; A_N(4,1) = 5; % This function makes the initial corners of the 4x4
                    A_N = Moving_The_Numbers(app,A_N,n,m); % moving the numbers
                    [A_N,E_W_Canceled,N_S_Canceled] = Filling_In(app,A_N,n,m); % filling in the numbers
                    [A_N,E_W_Canceled,N_S_Canceled] = Canceling_The_Numbers(app,A_N,n,m,E_W_Canceled,N_S_Canceled);
                elseif counter >= 2 % all the other runs
                    % this part is how we get out of the loop
                    if counter  == N_count
                        image(app.UIAxes,A_N);
                        RGB_Values_2 = [0 0 0;0 0 0;0 0 0;0 0 0;1 1 1;0 0 1;1 0 0;0 1 0;1 1 0];
                        colormap(app.UIAxes,RGB_Values_2)
                        break
                    end
                    if counter > 1
                        [A_N,~,~] = Canceling_The_Numbers(app,A_N,n,m,E_W_Canceled,N_S_Canceled);
                    end
                    % the start of a new one
                    A_N = Making_Zeroes(app,n,A_N);
                    [n,m] = size(A_N);
                    A_N = Moving_And_Making_5(app,A_N,n,m);
                    A_N = Moving_The_Numbers(app,A_N,n,m);
                    [A_N,E_W_Canceled,N_S_Canceled] = Filling_In(app,A_N,n,m); % filling in the numbers
                end
                counter = counter + 1; % This is mainly me trying to determine the first initial condition
            end
            
        end

        % Callback function
        function CheckBoxValueChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NumberofrunsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofrunsEditField.Limits = [3 Inf];
            app.NumberofrunsEditField.Value = 10;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.AztecNumberALabel = uilabel(app.UIFigure);
            app.AztecNumberALabel.Text = 'Aztec Number A(#)';
            app.Noteasofversion2errorsoccurwhenmakingA1orA2Label = uilabel(app.UIFigure);
            app.Noteasofversion2errorsoccurwhenmakingA1orA2Label.Text = {'Note: as of version 2'; 'errors occur when'; 'making A(1) or A(2)'};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Aztec Diamond')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Aztec_Diamond_V2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end